classdef visualizeECG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImportDataModelTab            matlab.ui.container.Tab
        ModelPanel                    matlab.ui.container.Panel
        ImportButton                  matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        TestingDataPanel              matlab.ui.container.Panel
        ImportButton_3                matlab.ui.control.Button
        EditField_3                   matlab.ui.control.EditField
        FeatureBreakdownTab           matlab.ui.container.Tab
        AllofthefeaturesweusedforthisclassifiercanbeLabel  matlab.ui.control.Label
        VariableNamesPanel            matlab.ui.container.Panel
        ImportButton_4                matlab.ui.control.Button
        EditField_4                   matlab.ui.control.EditField
        Feature1EditFieldLabel        matlab.ui.control.Label
        Feature1EditField             matlab.ui.control.EditField
        Feature2EditFieldLabel        matlab.ui.control.Label
        Feature2EditField             matlab.ui.control.EditField
        Feature3EditFieldLabel        matlab.ui.control.Label
        Feature3EditField             matlab.ui.control.EditField
        Feature4EditFieldLabel        matlab.ui.control.Label
        Feature4EditField             matlab.ui.control.EditField
        Feature5EditFieldLabel        matlab.ui.control.Label
        Feature5EditField             matlab.ui.control.EditField
        Feature6EditFieldLabel        matlab.ui.control.Label
        Feature6EditField             matlab.ui.control.EditField
        Feature7EditFieldLabel        matlab.ui.control.Label
        Feature7EditField             matlab.ui.control.EditField
        Feature8EditFieldLabel        matlab.ui.control.Label
        Feature8EditField             matlab.ui.control.EditField
        UpdateVarsButton              matlab.ui.control.Button
        PDPICETab                     matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        PDPICEDropDownLabel           matlab.ui.control.Label
        PDPICEDropDown                matlab.ui.control.DropDown
        PredictorToEvaluateDropDownLabel  matlab.ui.control.Label
        PredictorToEvaluateDropDown   matlab.ui.control.DropDown
        SecondPredictoroptionalDropDownLabel  matlab.ui.control.Label
        SecondPredictoroptionalDropDown  matlab.ui.control.DropDown
        PlotButton                    matlab.ui.control.Button
        CenteredICEonlyDropDownLabel  matlab.ui.control.Label
        CenteredICEonlyDropDown       matlab.ui.control.DropDown
        ClassToLookAtDropDownLabel    matlab.ui.control.Label
        ClassToLookAtDropDown         matlab.ui.control.DropDown
        LIMETab                       matlab.ui.container.Tab
        NumberImportantVariablesEditField  matlab.ui.control.NumericEditField
        NumberofImportantVariablesLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
        PlotLimeButton                matlab.ui.control.Button
        TestDataSelectObservationToExplainLabel  matlab.ui.control.Label
        ECGClassificationLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        mdl
        variableNames
        trainData
        testData
        row
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path]= uigetfile('*.mat', 'Load Model Into Workspace');
            model = load([path file]);
            f = fields(model);
            
            
            model = model.(f{1});
            
            % Extract core model type from struct if applciable
            if string(class(model)) == "struct"
                % Remove all common learner struct fields - what remains is
                % the learner name
                structFields = string(fields(model));
                learnerAppFields = ["predictFcn","RequiredVariables","RegressionEnsemble","About","HowToPredict"];
                learnerName = setdiff(structFields,learnerAppFields);
                
                model = model.(learnerName);
            end
            app.mdl = model;
            app.EditField.Value = file;
            drawnow;
            figure(app.UIFigure)
        end

        % Callback function
        function ImportButton_2Pushed(app, event)
            [file, path]= uigetfile('*.mat', 'Load Training Data Into Workspace');
            app.trainData = load([path file]);
            app.EditField_2.Value = file;
            
        end

        % Button pushed function: ImportButton_3
        function ImportButton_3Pushed(app, event)
            [file, path]= uigetfile('*.mat', 'Load Testing Data Into Workspace');
            app.testData = load([path file]);
            app.EditField_3.Value = file;
            app.UITable.Data = app.testData.testData;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: ImportButton_4
        function ImportButton_4Pushed(app, event)
            [file, path]= uigetfile('*.mat', 'Enter Variable Names');
            app.variableNames = load([path file]);
            app.EditField_4.Value = file;
            drawnow;
            figure(app.UIFigure)
            app.PredictorToEvaluateDropDown.Items = app.variableNames.variableNames(1:end-1);
            app.variableNames = app.variableNames.variableNames(1:end-1);
            app.SecondPredictoroptionalDropDown.Items = ['None',app.variableNames];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Vars = app.PredictorToEvaluateDropDown.Value;
            labelToPlot = app.ClassToLookAtDropDown.Value;
            
            
            if strcmp(labelToPlot,'Normal')
                labelToPlot = 1;
            else
                labelToPlot = 2;
            end
            
            if ~strcmp(app.SecondPredictoroptionalDropDown.Value , 'None')
               secondVars = app.SecondPredictoroptionalDropDown.Value;
               firstVar = find(app.variableNames == convertCharsToStrings(Vars));
               secondVar = find(app.variableNames == convertCharsToStrings(secondVars));
               Vars = [firstVar, secondVar];
            else
                Vars = find(app.variableNames == convertCharsToStrings(Vars));
            end
            
            if strcmp(app.PDPICEDropDown.Value, 'PDP')
              pdpVals =  partialDependence(app.mdl,Vars, labelToPlot);
            else
                if strcmp(app.CenteredICEonlyDropDown, 'Yes')
                    
                    pdpVals = partialDependence(app.mdl, Vars(1), labelToPlot, 'Conditional','centered');
                else
                    pdpVals = partialDependence(app.mdl, Vars(1), labelToPlot, 'Conditional','absolute');
                end
            end
           plot(app.UIAxes,pdpVals)
           app.UIAxes.XLabel.String = Vars(1);
           if strcmp(app.SecondPredictoroptionalDropDown.Value , 'None')
                app.UIAxes.YLabel.String = app.ClassToLookAtDropDown.Value;
           else
               app.UIAxes.YLabel.String = Vars(2);
           end
           app.UIAxes.Title.String = "PDP/ICE Plot";
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.row = indices(1,1);


        end

        % Button pushed function: UpdateVarsButton
        function UpdateVarsButtonPushed(app, event)
            app.variableNames = cell(1,8);
            app.variableNames{1} = app.Feature1EditField.Value;
            app.variableNames{2} = app.Feature2EditField.Value;
            app.variableNames{3} = app.Feature3EditField.Value;
            app.variableNames{4} = app.Feature4EditField.Value;
            app.variableNames{5} = app.Feature5EditField.Value;
            app.variableNames{6} = app.Feature6EditField.Value;
            app.variableNames{7} = app.Feature7EditField.Value;
            app.variableNames{8} = app.Feature8EditField.Value;
            app.PredictorToEvaluateDropDown.Items = app.variableNames;
            app.SecondPredictoroptionalDropDown.Items = ['None', app.variableNames];
            app.UITable.ColumnName = app.variableNames;
        end

        % Button pushed function: PlotLimeButton
        function PlotLimeButtonPushed(app, event)
            app.testData = app.testData.testData;
            % create lime object
            limeobj = lime(app.mdl, 'Type','classification');
            
            % use lime object to get explanations for the first observation in our test
            % class. Look at the first three important predictors
            limeobj = fit(limeobj,app.testData(app.row,1:end-1),app.NumberImportantVariablesEditField.Value);
            p = plot(limeobj);
            f = p.Parent.CurrentFigure;
            f.Visible = 'off';
            ax = f.Children;
            copyobj(ax.Children, app.UIAxes2);
            app.UIAxes2.Title.String = ax.Title.String;
            app.UIAxes2.XLabel.String = ax.XLabel.String;
            app.UIAxes2.YLabel.String = ax.YLabel.String;
            app.UIAxes2.Subtitle.String = ax.Subtitle.String;
            app.UIAxes2.YTick = ax.YTick;
            app.UIAxes2.YTickLabel = ax.YTickLabel;
            close(f);
           % subtitle, title, xlable, ylabel, toolb
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportDataModelTab = uitab(app.TabGroup);
            app.ImportDataModelTab.Title = 'Import Data/Model';
            app.ModelPanel = uipanel(app.ImportDataModelTab);
            app.ModelPanel.Title = 'Model';
            app.ImportButton = uibutton(app.ModelPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.EditField = uieditfield(app.ModelPanel, 'text');
            app.EditField.Editable = 'off';
            app.TestingDataPanel = uipanel(app.ImportDataModelTab);
            app.TestingDataPanel.Title = 'Testing Data';
            app.ImportButton_3 = uibutton(app.TestingDataPanel, 'push');
            app.ImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_3Pushed, true);
            app.ImportButton_3.Text = 'Import';
            app.EditField_3 = uieditfield(app.TestingDataPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.FeatureBreakdownTab = uitab(app.TabGroup);
            app.FeatureBreakdownTab.Title = 'Feature Breakdown';
            app.AllofthefeaturesweusedforthisclassifiercanbeLabel = uilabel(app.FeatureBreakdownTab);
            app.AllofthefeaturesweusedforthisclassifiercanbeLabel.Text = {'There are 8 Variables that are used as Input into the model. '; 'The variables are in this order:'; ''; '1. The interval between beat n-2 and beat n-1'; '2. The interval between beat n-1 and beat n'; '3. The interval between beat n and n + 1'; '4. The ratio of feature (1) over feature (2)'; '5. The ratio of feature (3) over feature (2)'; '6. The mean of features 1 to 3, divided by feature (2)'; '7. The amplitude of beat n (channel 1)'; '8. The amplitude of beat n (channel 2)'; ''; 'Import your custom variables names below as cell array. '; ''; 'Alternatively, manually enter custom names to the right.'; ''; ''};
            app.VariableNamesPanel = uipanel(app.FeatureBreakdownTab);
            app.VariableNamesPanel.Title = 'Variable Names';
            app.ImportButton_4 = uibutton(app.VariableNamesPanel, 'push');
            app.ImportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_4Pushed, true);
            app.ImportButton_4.Text = 'Import';
            app.EditField_4 = uieditfield(app.VariableNamesPanel, 'text');
            app.Feature1EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature1EditFieldLabel.Text = 'Feature 1';
            app.Feature1EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature1EditField.Value = 'RR0';
            app.Feature2EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature2EditFieldLabel.Text = 'Feature 2';
            app.Feature2EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature2EditField.Value = 'RR1';
            app.Feature3EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature3EditFieldLabel.Text = 'Feature 3';
            app.Feature3EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature3EditField.Value = 'RR2';
            app.Feature4EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature4EditFieldLabel.Text = 'Feature 4';
            app.Feature4EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature4EditField.Value = 'R1';
            app.Feature5EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature5EditFieldLabel.Text = 'Feature 5';
            app.Feature5EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature5EditField.Value = 'R2';
            app.Feature6EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature6EditFieldLabel.Text = 'Feature 6';
            app.Feature6EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature6EditField.Value = 'Rm';
            app.Feature7EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature7EditFieldLabel.Text = 'Feature 7';
            app.Feature7EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature7EditField.Value = 'rAmplitude1';
            app.Feature8EditFieldLabel = uilabel(app.FeatureBreakdownTab);
            app.Feature8EditFieldLabel.Text = 'Feature 8';
            app.Feature8EditField = uieditfield(app.FeatureBreakdownTab, 'text');
            app.Feature8EditField.Value = 'rAmplitude2';
            app.UpdateVarsButton = uibutton(app.FeatureBreakdownTab, 'push');
            app.UpdateVarsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVarsButtonPushed, true);
            app.UpdateVarsButton.Text = 'Update Vars';
            app.PDPICETab = uitab(app.TabGroup);
            app.PDPICETab.Title = 'PDP/ICE';
            app.UIAxes = uiaxes(app.PDPICETab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PDPICEDropDownLabel = uilabel(app.PDPICETab);
            app.PDPICEDropDownLabel.Text = 'PDP/ICE';
            app.PDPICEDropDown = uidropdown(app.PDPICETab);
            app.PDPICEDropDown.Items = {'PDP', 'ICE'};
            app.PDPICEDropDown.Value = 'PDP';
            app.PredictorToEvaluateDropDownLabel = uilabel(app.PDPICETab);
            app.PredictorToEvaluateDropDownLabel.Text = 'Predictor To Evaluate';
            app.PredictorToEvaluateDropDown = uidropdown(app.PDPICETab);
            app.PredictorToEvaluateDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.PredictorToEvaluateDropDown.Value = '1';
            app.SecondPredictoroptionalDropDownLabel = uilabel(app.PDPICETab);
            app.SecondPredictoroptionalDropDownLabel.Text = 'Second Predictor (optional)';
            app.SecondPredictoroptionalDropDown = uidropdown(app.PDPICETab);
            app.SecondPredictoroptionalDropDown.Items = {'None', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.SecondPredictoroptionalDropDown.Value = 'None';
            app.PlotButton = uibutton(app.PDPICETab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CenteredICEonlyDropDownLabel = uilabel(app.PDPICETab);
            app.CenteredICEonlyDropDownLabel.Text = 'Centered (ICE only)';
            app.CenteredICEonlyDropDown = uidropdown(app.PDPICETab);
            app.CenteredICEonlyDropDown.Items = {'Yes', 'No'};
            app.CenteredICEonlyDropDown.Value = 'Yes';
            app.ClassToLookAtDropDownLabel = uilabel(app.PDPICETab);
            app.ClassToLookAtDropDownLabel.Text = 'Class To Look At';
            app.ClassToLookAtDropDown = uidropdown(app.PDPICETab);
            app.ClassToLookAtDropDown.Items = {'Normal', 'Abnormal'};
            app.ClassToLookAtDropDown.Value = 'Normal';
            app.LIMETab = uitab(app.TabGroup);
            app.LIMETab.Title = 'LIME';
            app.NumberImportantVariablesEditField = uieditfield(app.LIMETab, 'numeric');
            app.NumberofImportantVariablesLabel = uilabel(app.LIMETab);
            app.NumberofImportantVariablesLabel.Text = 'Number of Important Variables';
            app.UIAxes2 = uiaxes(app.LIMETab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UITable = uitable(app.LIMETab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PlotLimeButton = uibutton(app.LIMETab, 'push');
            app.PlotLimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLimeButtonPushed, true);
            app.PlotLimeButton.Text = 'Plot Lime';
            app.TestDataSelectObservationToExplainLabel = uilabel(app.LIMETab);
            app.TestDataSelectObservationToExplainLabel.Text = {'Test Data: Select Observation To Explain:'; '(Select only one row to use as observation'; 'Pick any cell within that row to select'; 'that entire observation)'};
            app.ECGClassificationLabel = uilabel(app.UIFigure);
            app.ECGClassificationLabel.Text = 'ECG Classification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeECG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end